classdef showimg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ThreshholdSlider  matlab.ui.control.Slider
        ThreshholdLabel   matlab.ui.control.Label
        Button            matlab.ui.control.Button
        computeButton     matlab.ui.control.Button
        loadButton        matlab.ui.control.Button
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
        ContextMenu       matlab.ui.container.ContextMenu
        Menu              matlab.ui.container.Menu
        Menu2             matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            imshow('logo_csse.png','Parent',app.UIAxes);
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            BW = binarize('logo_csse.png');
            imshow(BW*255,'Parent',app.UIAxes2);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            vfs =  FourierTransform('logo_csse.png',0.1);
            imshow(mat2gray(vfs), 'Parent',app.UIAxes3);
            app.ThreshholdSlider.Limits = [0 1.414];
        end

        % Value changed function: ThreshholdSlider
        function ThreshholdSliderValueChanged(app, event)
            
            threshhold = app.ThreshholdSlider.Value;
            
            transform_image = FourierTransform('logo_csse.png', threshhold);
            imshow(mat2gray(transform_image), 'Parent',app.UIAxes3)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '画像表示１')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '二値化')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'フーリエ変換→ローパスフィルタ→逆フーリエ変換')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ThreshholdLabel = uilabel(app.UIFigure);
            app.ThreshholdLabel.Text = 'Threshhold(平滑化）';
            app.ThreshholdSlider = uislider(app.UIFigure);
            app.ThreshholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThreshholdSliderValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIAxes3.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showimg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end